classdef guiPrototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SynthlabPROTOTYPELabel  matlab.ui.control.Label
        PLAYButton              matlab.ui.control.Button
        SliderLabel             matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
    end


    properties (Access = private)
        decFreq = 750; % frequency
    end

    methods (Static)
    
        function TestTone(app, freq)
            % Simple sine wave
            amp = 0.05;
%             freq = 750;
            dur = 1;
            riseDur = 0;
            Fs = 48000;
            phase = 0;
            [tone, tVec] = app.genTone(amp, freq, dur, riseDur, phase, Fs);
        
            % clf
            % plot(tVec, tone)
            % ylabel('Amp, V')
            % xlabel('Time, S')
            % hold on
            % scatter(tVec, tone)
        
            p = audioplayer(tone, Fs);
            playblocking(p);
        end
       
        function [tone, tVec, env] = genTone(amp, freq, dur, riseDur, phase, Fs)
            % amp = amplitude, arb. (eg. Volts)
            % freq = frequency, Hz
            % dur = duration, S
            % riseDur = rise/fall time, in S
            % Fs = Sampling frequency, Hz
            
            % Calculate number of points required
            dt = 1/Fs;
            % Length in pts
            % npts (pts) = dur (S) * Fs (pts/S)
            nPts = round(dur * Fs);
            
            % Generate time vector
            tVec = 0:dt:dur-dt;
            
            % Generate tone
            tone = amp * sin(pi*2*freq.*tVec + phase);
            
            % Generate rise/fall
            nPtsRise = round(riseDur * Fs);
            tVecRise = -pi/2 : abs(-pi/2-0)/(nPtsRise-1) : 0;
            % Equiv to linspace(-pi/2, 0, nPtsRise)
            
            % Generate rise
            rise = cos(tVecRise);
            fall = rise(end:-1:1);
            
            % Add to envelope
            env = ones(1, nPts);
            env(1:nPtsRise) = rise;
            env(end-(nPtsRise-1):end) = fall;
            
            % Apply envelope to tone
            tone = env .* tone;
        end
    end

    methods (Access = private)
    
%         function player = GetNewPlayer(app)
%             player = new 
%         end
        
    end


    methods (Access = private)

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            disp('Before TestTone');
            app.TestTone(app, app.decFreq);
            disp('After TestTone');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.decFreq = app.Slider.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SynthlabPROTOTYPELabel = uilabel(app.UIFigure);
            app.SynthlabPROTOTYPELabel.Text = 'Synthlab – PROTOTYPE';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [500 15000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 2500;
        end
    end

    methods (Access = public)

        % Construct app
        function app = guiPrototype

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end